classdef DigitalGuitar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Label_Title        matlab.ui.control.Label
        Panel_Basics       matlab.ui.container.Panel
        Label_N            matlab.ui.control.Label
        Label_4            matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        Label              matlab.ui.control.Label
        Label_kgm3         matlab.ui.control.Label
        Label_m            matlab.ui.control.Label
        PluckButton        matlab.ui.control.Button
        ToggleTuning       matlab.ui.control.Switch
        SliderReverb       matlab.ui.control.Slider
        EditField_Fs       matlab.ui.control.NumericEditField
        FsEditFieldLabel   matlab.ui.control.Label
        EditTension        matlab.ui.control.NumericEditField
        TEditFieldLabel    matlab.ui.control.Label
        EditDensity        matlab.ui.control.NumericEditField
        rEditFieldLabel    matlab.ui.control.Label
        EditLength         matlab.ui.control.NumericEditField
        StatusLight        matlab.ui.control.Lamp
        LEditFieldLabel    matlab.ui.control.Label
        Panel_Extension    matlab.ui.container.Panel
        Label_m_3          matlab.ui.control.Label
        Label_m_2          matlab.ui.control.Label
        ForceTypeDropdown  matlab.ui.control.DropDown
        Label_8            matlab.ui.control.Label
        EditPosition       matlab.ui.control.NumericEditField
        Label_7            matlab.ui.control.Label
        EditFrequency      matlab.ui.control.NumericEditField
        Label_6            matlab.ui.control.Label
        EditAmplitude      matlab.ui.control.NumericEditField
        Label_5            matlab.ui.control.Label
        Panel_WaveShow     matlab.ui.container.Panel
        AxesFrequency      matlab.ui.control.UIAxes
        AxesWaveform       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        IsCalculating = false;  % 计算状态锁
        Fs = 44100;             % 采样率
        CurrentAudio;           % 当前音频数据
        Debug = struct();       % 添加调试属性
    end
    
    methods (Access = private)

        function [f, P1] = computeSpectrum(~, y, Fs)
            N = length(y);
            Y = fft(y);
            P2 = abs(Y/N);
            P1 = P2(1:floor(N/2)+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(length(P1)-1))/N;
        end
        
        function updatePluckStatus(app, color)
            % 更新运算状态指示灯
            app.StatusLight.Color = color;
            drawnow;
        end
        
         function forceFunc = createForceFunc(app)
            % 根据用户输入创建受迫力函数
            forceType = app.ForceTypeDropdown.Value;
            amp = app.EditAmplitude.Value;
            freq = app.EditFrequency.Value;
            pos = app.EditPosition.Value;
            
            switch forceType
                case '点激励'
                    % 增加作用范围灵敏度
                    forceFunc = @(x,t) amp * exp(-2000*(x-pos).^2) .* sin(2*pi*freq*t);
                case '谐波激励'
                    % 添加高频谐波成分
                    forceFunc = @(x,t) amp * (sin(2*pi*freq*t) + 0.5*sin(2*pi*2*freq*t)) ...
                        .* exp(-100*(x-pos).^2);
                otherwise
                    forceFunc = [];
            end
         end
         
          % 修改后的修音函数
        function y_tuned = applyTuningEffect(~, y, Fs)
            % 添加Fs参数输入
            fc = 1800;
            [b,a] = butter(6, fc/(Fs/2), 'low');
            y_tuned = filtfilt(b,a,y);
        end

        % 改进后的混响函数
        function y_reverb = applyReverb(~, y, Fs, reverbTime)
            if reverbTime <= 0
                y_reverb = y;
                return
            end
            
            % 使用Schroeder混响模型
            numDelays = 4;
            delays = round([29.7, 37.1, 41.1, 43.7] * Fs/1000);
            gains = 0.7.^(0:numDelays-1);
            
            % 确保输入为列向量
            y = y(:);
            y_reverb = zeros(size(y));
            
            for i = 1:numDelays
                % 动态调整延迟时间避免越界
                current_delay = min(delays(i), length(y)-1);
                
                % 通过索引填充延迟信号
                delayed = zeros(size(y));
                if current_delay > 0
                    delayed(current_delay+1:end) = y(1:end-current_delay);
                else
                    delayed = y;
                end
                
                % 累加处理
                y_reverb = y_reverb + gains(i) * delayed;
            end
            
            % 添加衰减包络
            t = (0:length(y)-1)/Fs;
            decay = exp(-5/reverbTime * t);
            y_reverb = y_reverb(:) .* decay(:);
        end
    
         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PluckButton
        function PluckButtonPushed(app, event)

            if app.IsCalculating, return; end % 防止重复点击
            app.IsCalculating = true;

            try
                % 获取参数
                L = app.EditLength.Value;
                rho = app.EditDensity.Value;
                T = app.EditTension.Value;
                reverbTime = app.SliderReverb.Value;
                
                updatePluckStatus(app, [0.5 0.5 0.5]);
                
                % 求解波动方程
                forceFunc = createForceFunc(app);
                [~, y] = SolveWaveEquation(T, rho, L, 5, app.Fs,...
                    'ForceFunc', forceFunc, 'EnablePluck', isempty(forceFunc));
                
                % 应用效果链
                y_adsr = applyADSR(y, app.Fs, 0.01, 0.1, 0.7, 0.2); % 添加完整参数

                if app.ToggleTuning.Value == "On"
                    app.Debug.Original = y_adsr;
                    y_tuned = applyTuningEffect(app, y_adsr, app.Fs);
                    app.Debug.Filtered = y_tuned;
                else
                    y_tuned = y_adsr;
                end
               
                y_reverb = applyReverb(app, y_tuned, app.Fs, reverbTime);
                
                % 更新音频数据（强制列向量）
                app.CurrentAudio = y_reverb(:);
                t = (0:length(app.CurrentAudio)-1)/app.Fs;

                % 修正波形图和频谱图的显示
                app.CurrentAudio = y_reverb(:);

                % 波形绘制
                cla(app.AxesWaveform);
                plot(app.AxesWaveform, t, app.CurrentAudio);
                app.AxesWaveform.XLabel.String = 'Time (s)';
                app.AxesWaveform.YLabel.String = 'Amplitude';
                xlim(app.AxesWaveform, [0 t(end)]);
                
                % 频谱绘制（增强对比显示）
                % cla(app.AxesFrequency);
                % [f, P1] = computeSpectrum(app, app.CurrentAudio, app.Fs);
                % 
                % if app.ToggleTuning.Value == "On"
                %     [~, P1_orig] = computeSpectrum(app, app.Debug.Original, app.Fs);
                %     plot(app.AxesFrequency, f, 20*log10(P1_orig), 'Color', [0.5 0.5 0.5]);
                %     hold(app.AxesFrequency, 'on');
                % end
                % 
                % plot(app.AxesFrequency, f, 20*log10(P1), 'Color', [0.8500 0.3250 0.0980]);
                % hold(app.AxesFrequency, 'off');
                % legend(app.AxesFrequency, {'处理信号', '原始信号'});
                
                cla(app.AxesFrequency);
                [f, P1] = computeSpectrum(app, app.CurrentAudio, app.Fs);
                
                if app.ToggleTuning.Value == "On"
                    % 计算原始信号频谱
                    [~, P1_orig] = computeSpectrum(app, app.Debug.Original, app.Fs);
                    
                    % 绘制双曲线（统一使用hold管理）
                    plot(app.AxesFrequency, f, 20*log10(P1_orig), 'Color', [1 0.1 0.2], 'DisplayName', '原始信号');
                    hold(app.AxesFrequency, 'on');
                    plot(app.AxesFrequency, f, 20*log10(P1), 'Color', [0.4 0 0.8], 'DisplayName', '处理信号');
                    hold(app.AxesFrequency, 'off');
                    
                    % 设置图例（仅一次调用）
                    legend(app.AxesFrequency, 'show');
                else
                    % 仅绘制原始信号
                    plot(app.AxesFrequency, f, 20*log10(P1), 'Color', [0.4 0 0.8], 'DisplayName', '原始信号');
                    legend(app.AxesFrequency, 'off'); % 关闭图例或显示单一标签
                end


                % 播放音频
                soundsc(app.CurrentAudio, app.Fs);
                updatePluckStatus(app, [0 1 0]);
                
            catch ME
                updatePluckStatus(app, [1 0 0]);
                errordlg(sprintf('发生错误:\n%s', ME.message), '错误');
            end

            app.IsCalculating = false;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_WaveShow = uipanel(app.UIFigure);
            app.Panel_WaveShow.Title = '波形显示区';
            app.AxesWaveform = uiaxes(app.Panel_WaveShow);
            title(app.AxesWaveform, '波形图')
            xlabel(app.AxesWaveform, 'Time(s)')
            ylabel(app.AxesWaveform, 'Amplitude')
            app.AxesFrequency = uiaxes(app.Panel_WaveShow);
            title(app.AxesFrequency, '频率图')
            xlabel(app.AxesFrequency, 'Frequency(Hz)')
            ylabel(app.AxesFrequency, 'Amplitude')
            app.AxesFrequency.XLim = [100 2000];
            app.AxesFrequency.XTick = [100 200 300 400 500 600 700 800 900 1000 1100 1200 1300 1400 1500 1600 1700 1800 1900 2000];
            app.AxesFrequency.XTickLabel = {'100'; '200'; '300'; '400'; '500'; '600'; '700'; '800'; '900'; '1000'; '1100'; '1200'; '1300'; '1400'; '1500'; '1600'; '1700'; '1800'; '1900'; '2000'};
            app.Panel_Extension = uipanel(app.UIFigure);
            app.Panel_Extension.Title = '拓展属性区';
            app.Label_5 = uilabel(app.Panel_Extension);
            app.Label_5.Text = '幅值';
            app.EditAmplitude = uieditfield(app.Panel_Extension, 'numeric');
            app.EditAmplitude.Value = 1;
            app.Label_6 = uilabel(app.Panel_Extension);
            app.Label_6.Text = '频率';
            app.EditFrequency = uieditfield(app.Panel_Extension, 'numeric');
            app.EditFrequency.Value = 200;
            app.Label_7 = uilabel(app.Panel_Extension);
            app.Label_7.Text = '位置';
            app.EditPosition = uieditfield(app.Panel_Extension, 'numeric');
            app.EditPosition.Value = 0.2;
            app.Label_8 = uilabel(app.Panel_Extension);
            app.Label_8.Text = '受迫力';
            app.ForceTypeDropdown = uidropdown(app.Panel_Extension);
            app.ForceTypeDropdown.Items = {'无', '点激励', '谐波激励'};
            app.ForceTypeDropdown.Value = '无';
            app.Label_m_2 = uilabel(app.Panel_Extension);
            app.Label_m_2.Text = 'm';
            app.Label_m_3 = uilabel(app.Panel_Extension);
            app.Label_m_3.Text = 'Hz';
            app.Panel_Basics = uipanel(app.UIFigure);
            app.Panel_Basics.Title = '基本属性区';
            app.LEditFieldLabel = uilabel(app.Panel_Basics);
            app.LEditFieldLabel.Text = '弦长L';
            app.StatusLight = uilamp(app.Panel_Basics);
            app.EditLength = uieditfield(app.Panel_Basics, 'numeric');
            app.EditLength.ValueDisplayFormat = '%.3f';
            app.EditLength.Value = 0.65;
            app.rEditFieldLabel = uilabel(app.Panel_Basics);
            app.rEditFieldLabel.Text = '弦密度r';
            app.EditDensity = uieditfield(app.Panel_Basics, 'numeric');
            app.EditDensity.ValueDisplayFormat = '%.8f';
            app.EditDensity.Value = 0.0027646;
            app.TEditFieldLabel = uilabel(app.Panel_Basics);
            app.TEditFieldLabel.Text = '弦张力T';
            app.EditTension = uieditfield(app.Panel_Basics, 'numeric');
            app.EditTension.ValueDisplayFormat = '%.2f';
            app.EditTension.Value = 179.48;
            app.FsEditFieldLabel = uilabel(app.Panel_Basics);
            app.FsEditFieldLabel.Enable = 'off';
            app.FsEditFieldLabel.Text = '采样率Fs';
            app.EditField_Fs = uieditfield(app.Panel_Basics, 'numeric');
            app.EditField_Fs.ValueDisplayFormat = '%.0f';
            app.EditField_Fs.Enable = 'off';
            app.EditField_Fs.Value = 44100;
            app.SliderReverb = uislider(app.Panel_Basics);
            app.SliderReverb.Limits = [0 10];
            app.SliderReverb.Value = 1;
            app.ToggleTuning = uiswitch(app.Panel_Basics, 'slider');
            app.PluckButton = uibutton(app.Panel_Basics, 'push');
            app.PluckButton.ButtonPushedFcn = createCallbackFcn(app, @PluckButtonPushed, true);
            app.PluckButton.Text = '拨弦';
            app.Label_m = uilabel(app.Panel_Basics);
            app.Label_m.Text = 'm';
            app.Label_kgm3 = uilabel(app.Panel_Basics);
            app.Label_kgm3.Text = 'kg/m^3';
            app.Label = uilabel(app.Panel_Basics);
            app.Label.Text = '混响时长';
            app.Label_2 = uilabel(app.Panel_Basics);
            app.Label_2.Text = '修音';
            app.Label_4 = uilabel(app.Panel_Basics);
            app.Label_4.Text = '运算状态';
            app.Label_N = uilabel(app.Panel_Basics);
            app.Label_N.Text = 'N';
            app.Label_Title = uilabel(app.UIFigure);
            app.Label_Title.Text = '基于波动方程的数字吉他';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DigitalGuitar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end